classdef ImageFeaturesAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CataloguePanel                matlab.ui.container.Panel
        ExtensionDropDownLabel        matlab.ui.control.Label
        ExtensionDropDown             matlab.ui.control.DropDown
        ChoosepathButton              matlab.ui.control.Button
        ImagesPanel                   matlab.ui.container.Panel
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        PreviousButton                matlab.ui.control.Button
        NextButton                    matlab.ui.control.Button
        stimageEditFieldLabel         matlab.ui.control.Label
        stimageEditField              matlab.ui.control.NumericEditField
        ndimageEditFieldLabel         matlab.ui.control.Label
        ndimageEditField              matlab.ui.control.NumericEditField
        OptionsPanel                  matlab.ui.container.Panel
        DescriptorsButtonGroup        matlab.ui.container.ButtonGroup
        SURFButton                    matlab.ui.control.ToggleButton
        BRISKButton                   matlab.ui.control.ToggleButton
        HOGButton                     matlab.ui.control.ToggleButton
        FASTButton                    matlab.ui.control.ToggleButton
        ORBButton                     matlab.ui.control.ToggleButton
        MSERButton                    matlab.ui.control.ToggleButton
        HARRISButton                  matlab.ui.control.ToggleButton
        ColormodelSwitchLabel         matlab.ui.control.Label
        ColormodelSwitch              matlab.ui.control.Switch
        prgpodobiestwaEditFieldLabel  matlab.ui.control.Label
        prgpodobiestwaEditField       matlab.ui.control.NumericEditField
        CompareButton                 matlab.ui.control.Button
        SaveButton                    matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChoosepathButton
        function ChoosepathButtonPushed(app, event)
            % Specify the folder where the files live. 
myFolder = uigetdir('C:\', 'Choose directory')                                              %obsługa wyjątku kiedy nic nie wybierzemy
% Check to make sure that folder actually exists.  Warn user if it doesn't.
if ~isdir(myFolder)
  errorMessage = sprintf('Error: The following folder does not exist:\n%s', myFolder);
  uiwait(warndlg(errorMessage));
  return;
end
% Get a list of all files in the folder with the desired file name pattern.
filePattern = fullfile(myFolder, '*.jpg'); % Change to whatever pattern you need.           %case do roznych wybranych rozszerzen
theFiles = dir(filePattern);
for k = 1: length(theFiles)
  baseFileName = theFiles(k).name;
  fullFileName = fullfile(myFolder, baseFileName);
end
  file1 = fullfile(myFolder, theFiles(1).name);
  file2 = fullfile(myFolder, theFiles(2).name);
    I = imread(file1);
    imshow(I,'Parent', app.UIAxes);
    I2 = imread(file2);
    imshow(I2, 'Parent', app.UIAxes_2 );
  
  drawnow; % Force display to update immediately.


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.CataloguePanel = uipanel(app.UIFigure);
            app.CataloguePanel.Title = 'Catalogue';
            app.ExtensionDropDownLabel = uilabel(app.CataloguePanel);
            app.ExtensionDropDownLabel.Text = 'Extension';
            app.ExtensionDropDown = uidropdown(app.CataloguePanel);
            app.ExtensionDropDown.Items = {'.tif', '.dcm', '.jpg', '.png'};
            app.ExtensionDropDown.Value = '.tif';
            app.ChoosepathButton = uibutton(app.CataloguePanel, 'push');
            app.ChoosepathButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosepathButtonPushed, true);
            app.ChoosepathButton.Text = 'Choose path';
            app.ImagesPanel = uipanel(app.UIFigure);
            app.ImagesPanel.Title = 'Images';
            app.UIAxes_2 = uiaxes(app.ImagesPanel);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes = uiaxes(app.ImagesPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.PreviousButton = uibutton(app.ImagesPanel, 'push');
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.ImagesPanel, 'push');
            app.NextButton.Text = 'Next';
            app.stimageEditFieldLabel = uilabel(app.ImagesPanel);
            app.stimageEditFieldLabel.Text = '1st image';
            app.stimageEditField = uieditfield(app.ImagesPanel, 'numeric');
            app.stimageEditField.Value = 1;
            app.ndimageEditFieldLabel = uilabel(app.ImagesPanel);
            app.ndimageEditFieldLabel.Text = '2nd image';
            app.ndimageEditField = uieditfield(app.ImagesPanel, 'numeric');
            app.ndimageEditField.Value = 2;
            app.OptionsPanel = uipanel(app.UIFigure);
            app.OptionsPanel.Title = 'Options';
            app.DescriptorsButtonGroup = uibuttongroup(app.OptionsPanel);
            app.DescriptorsButtonGroup.Title = 'Descriptors';
            app.SURFButton = uitogglebutton(app.DescriptorsButtonGroup);
            app.SURFButton.Text = 'SURF';
            app.SURFButton.Value = true;
            app.BRISKButton = uitogglebutton(app.DescriptorsButtonGroup);
            app.BRISKButton.Text = 'BRISK';
            app.HOGButton = uitogglebutton(app.DescriptorsButtonGroup);
            app.HOGButton.Text = 'HOG';
            app.FASTButton = uitogglebutton(app.DescriptorsButtonGroup);
            app.FASTButton.Text = 'FAST';
            app.ORBButton = uitogglebutton(app.DescriptorsButtonGroup);
            app.ORBButton.Text = 'ORB';
            app.MSERButton = uitogglebutton(app.DescriptorsButtonGroup);
            app.MSERButton.Text = 'MSER';
            app.HARRISButton = uitogglebutton(app.DescriptorsButtonGroup);
            app.HARRISButton.Text = 'HARRIS';
            app.ColormodelSwitchLabel = uilabel(app.OptionsPanel);
            app.ColormodelSwitchLabel.Text = 'Color model';
            app.ColormodelSwitch = uiswitch(app.OptionsPanel, 'slider');
            app.ColormodelSwitch.Items = {'RGB', 'G+B'};
            app.ColormodelSwitch.Value = 'RGB';
            app.prgpodobiestwaEditFieldLabel = uilabel(app.OptionsPanel);
            app.prgpodobiestwaEditFieldLabel.Text = 'próg podobieństwa';
            app.prgpodobiestwaEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.CompareButton = uibutton(app.OptionsPanel, 'push');
            app.CompareButton.Text = 'Compare';
            app.SaveButton = uibutton(app.OptionsPanel, 'push');
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageFeaturesAnalysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end